classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ObjectSegmentationTab         matlab.ui.container.Tab
        ImageSelectedforSegmentation  matlab.ui.control.Label
        ImageSelectedLabel_2          matlab.ui.control.Label
        SelectImageButtonforSegmentation  matlab.ui.control.Button
        StandardDeviationLoGSpinner   matlab.ui.control.Spinner
        StandardDeviationLoGSpinnerLabel  matlab.ui.control.Label
        OutputPanel                   matlab.ui.container.Panel
        OutputSegmentation            matlab.ui.control.UIAxes
        EdgePanel                     matlab.ui.container.Panel
        EdgeSegmentation              matlab.ui.control.UIAxes
        InputPanel                    matlab.ui.container.Panel
        InputSegmentation             matlab.ui.control.UIAxes
        SelectEdgeDetectionAlgorithmDropDown  matlab.ui.control.DropDown
        SelectEdgeDetectionAlgorithmDropDownLabel  matlab.ui.control.Label
        LaneDetectionTab              matlab.ui.container.Tab
        HoughPanel                    matlab.ui.container.Panel
        HoughImage                    matlab.ui.control.UIAxes
        ImageSelectedforHough         matlab.ui.control.Label
        ImageSelectedLabel            matlab.ui.control.Label
        SelectImageButton             matlab.ui.control.Button
        OutputPanel_2                 matlab.ui.container.Panel
        OutputforHough                matlab.ui.control.UIAxes
        InputPanel_2                  matlab.ui.container.Panel
        InputforHough                 matlab.ui.control.UIAxes
        LaneDetectionwithHoughTransformationLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ImageInput % citra input
        ImageInputHough
        EdgeImage
        StdLoG
        OutputImageSegmentation
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButtonforSegmentation
        function SelectImageButtonforSegmentationPushed(app, event)
            % Pilih file gambar menggunakan uigetfile
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp; *.tif', 'Image Files (*.jpg, *.png, *.bmp, *.tif)'});
    
            % Jika pengguna memilih file
            if isequal(file, 0)
                disp('User cancelled the operation');
            else
                cla(app.InputSegmentation);
                % Baca gambar
                img = imread(fullfile(path, file));
                app.ImageSelectedforSegmentation.Text = file;
        
                % Tampilkan gambar di UIAxes
                imshow(img, 'Parent', app.InputSegmentation);
                app.ImageInput = img;
            end

            value = app.SelectEdgeDetectionAlgorithmDropDown.Value;
            
            switch value
                case 'Laplace'
                    app.StandardDeviationLoGSpinner.Visible = "off";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "off";
                    app.EdgeImage = laplace(app.ImageInput);
                case 'Laplace of Gaussian'
                    app.StandardDeviationLoGSpinner.Visible = "on";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "on";
                    app.EdgeImage = laplaceOfGaussian(app.ImageInput,or(app.StandardDeviationLoGSpinner.Value,1.4));
                case 'Sobel'
                    app.StandardDeviationLoGSpinner.Visible = "off";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "off";
                    app.EdgeImage = sobel_operator(app.ImageInput);
                case 'Prewitt'
                    app.StandardDeviationLoGSpinner.Visible = "off";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "off";
                    app.EdgeImage = prewitt_operator(app.ImageInput);
                case 'Roberts'
                    app.StandardDeviationLoGSpinner.Visible = "off";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "off";
                    app.EdgeImage = roberts(app.ImageInput);
                case 'Canny'
                    app.StandardDeviationLoGSpinner.Visible = "off";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "off";
                    app.EdgeImage = canny_operator(app.ImageInput);
            end
            imshow(app.EdgeImage, 'Parent', app.EdgeSegmentation); 
            app.OutputImageSegmentation = segmentation(app.EdgeImage,app.ImageInput);
            imshow(app.OutputImageSegmentation, 'Parent', app.OutputSegmentation);
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            % Pilih file gambar menggunakan uigetfile
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp; *.tif', 'Image Files (*.jpg, *.png, *.bmp, *.tif)'});
    
            % Jika pengguna memilih file
            if isequal(file, 0)
                disp('User cancelled the operation');
            else
                cla(app.InputforHough);
                app.StdLoG = 1.4;
                app.StandardDeviationLoGSpinner.Value = 1.4;
                % Baca gambar
                img = imread(fullfile(path, file));
                app.ImageSelectedforHough.Text = file;
        
                % Tampilkan gambar di UIAxes
                imshow(img, 'Parent', app.InputforHough);
                app.ImageInputHough = img;
            end

            % Hough
            [H,theta,rho,I] = lineDetectionHoughLibrary(app.ImageInputHough);
            imshow(I, 'Parent', app.OutputforHough);
            imshow(imadjust(mat2gray(H)), [], 'XData',theta, 'YData',rho, 'InitialMagnification','fit', 'Parent', app.HoughImage);
        end

        % Value changed function: SelectEdgeDetectionAlgorithmDropDown
        function SelectEdgeDetectionAlgorithmDropDownValueChanged(app, event)
            value = app.SelectEdgeDetectionAlgorithmDropDown.Value;
            
            switch value
                case 'Laplace'
                    app.StandardDeviationLoGSpinner.Visible = "off";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "off";
                    app.EdgeImage = laplace(app.ImageInput);
                case 'Laplace of Gaussian'
                    app.StandardDeviationLoGSpinner.Visible = "on";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "on";
                    app.EdgeImage = laplaceOfGaussian(app.ImageInput,or(app.StandardDeviationLoGSpinner.Value,1.4));
                case 'Sobel'
                    app.StandardDeviationLoGSpinner.Visible = "off";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "off";
                    app.EdgeImage = sobel_operator(app.ImageInput);
                case 'Prewitt'
                    app.StandardDeviationLoGSpinner.Visible = "off";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "off";
                    app.EdgeImage = prewitt_operator(app.ImageInput);
                case 'Roberts'
                    app.StandardDeviationLoGSpinner.Visible = "off";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "off";
                    app.EdgeImage = roberts(app.ImageInput);
                case 'Canny'
                    app.StandardDeviationLoGSpinner.Visible = "off";
                    app.StandardDeviationLoGSpinnerLabel.Visible = "off";
                    app.EdgeImage = canny_operator(app.ImageInput);
            end

            imshow(app.EdgeImage, 'Parent', app.EdgeSegmentation); 
            app.OutputImageSegmentation = segmentation(app.EdgeImage,app.ImageInput);
            imshow(app.OutputImageSegmentation, 'Parent', app.OutputSegmentation);
        end

        % Value changing function: StandardDeviationLoGSpinner
        function StandardDeviationLoGSpinnerValueChanging(app, event)
            changingValue = event.Value;
            
            app.EdgeImage = laplaceOfGaussian(app.ImageInput,changingValue);
            imshow(app.EdgeImage, 'Parent', app.EdgeSegmentation); 
            app.OutputImageSegmentation = segmentation(app.EdgeImage,app.ImageInput);
            imshow(app.OutputImageSegmentation, 'Parent', app.OutputSegmentation);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ObjectSegmentationTab = uitab(app.TabGroup);
            app.ObjectSegmentationTab.Title = 'Object Segmentation';
            app.SelectEdgeDetectionAlgorithmDropDownLabel = uilabel(app.ObjectSegmentationTab);
            app.SelectEdgeDetectionAlgorithmDropDownLabel.Text = 'Select Edge Detection Algorithm';
            app.SelectEdgeDetectionAlgorithmDropDown = uidropdown(app.ObjectSegmentationTab);
            app.SelectEdgeDetectionAlgorithmDropDown.Items = {'Laplace', 'Laplace of Gaussian', 'Sobel', 'Prewitt', 'Roberts', 'Canny'};
            app.SelectEdgeDetectionAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectEdgeDetectionAlgorithmDropDownValueChanged, true);
            app.SelectEdgeDetectionAlgorithmDropDown.Value = 'Laplace';
            app.InputPanel = uipanel(app.ObjectSegmentationTab);
            app.InputPanel.Title = 'Input';
            app.InputSegmentation = uiaxes(app.InputPanel);
            app.EdgePanel = uipanel(app.ObjectSegmentationTab);
            app.EdgePanel.Title = 'Edge';
            app.EdgeSegmentation = uiaxes(app.EdgePanel);
            app.OutputPanel = uipanel(app.ObjectSegmentationTab);
            app.OutputPanel.Title = 'Output';
            app.OutputSegmentation = uiaxes(app.OutputPanel);
            app.StandardDeviationLoGSpinnerLabel = uilabel(app.ObjectSegmentationTab);
            app.StandardDeviationLoGSpinnerLabel.Text = 'Standard Deviation (LoG)';
            app.StandardDeviationLoGSpinner = uispinner(app.ObjectSegmentationTab);
            app.StandardDeviationLoGSpinner.ValueChangingFcn = createCallbackFcn(app, @StandardDeviationLoGSpinnerValueChanging, true);
            app.StandardDeviationLoGSpinner.Value = 1.4;
            app.SelectImageButtonforSegmentation = uibutton(app.ObjectSegmentationTab, 'push');
            app.SelectImageButtonforSegmentation.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonforSegmentationPushed, true);
            app.SelectImageButtonforSegmentation.Text = 'Select Image';
            app.ImageSelectedLabel_2 = uilabel(app.ObjectSegmentationTab);
            app.ImageSelectedLabel_2.Text = 'Image Selected: ';
            app.ImageSelectedforSegmentation = uilabel(app.ObjectSegmentationTab);
            app.ImageSelectedforSegmentation.Text = '-';
            app.LaneDetectionTab = uitab(app.TabGroup);
            app.LaneDetectionTab.Title = 'Lane Detection';
            app.LaneDetectionwithHoughTransformationLabel = uilabel(app.LaneDetectionTab);
            app.LaneDetectionwithHoughTransformationLabel.Text = 'Lane Detection with Hough Transformation';
            app.InputPanel_2 = uipanel(app.LaneDetectionTab);
            app.InputPanel_2.Title = 'Input';
            app.InputforHough = uiaxes(app.InputPanel_2);
            title(app.InputforHough, 'Title')
            app.OutputPanel_2 = uipanel(app.LaneDetectionTab);
            app.OutputPanel_2.Title = 'Output';
            app.OutputforHough = uiaxes(app.OutputPanel_2);
            title(app.OutputforHough, 'Title')
            app.SelectImageButton = uibutton(app.LaneDetectionTab, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.ImageSelectedLabel = uilabel(app.LaneDetectionTab);
            app.ImageSelectedLabel.Text = 'Image Selected: ';
            app.ImageSelectedforHough = uilabel(app.LaneDetectionTab);
            app.ImageSelectedforHough.Text = '-';
            app.HoughPanel = uipanel(app.LaneDetectionTab);
            app.HoughPanel.Title = 'Hough';
            app.HoughImage = uiaxes(app.HoughPanel);
            title(app.HoughImage, 'Title')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end